classdef CEN320phase2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        TimedomainSpinnerLabel         matlab.ui.control.Label
        TimedomainSpinner              matlab.ui.control.Spinner
        FrequencySpinnerLabel          matlab.ui.control.Label
        FrequencySpinner               matlab.ui.control.Spinner
        NoofHarmonicsSpinnerLabel      matlab.ui.control.Label
        NoofHarmonicsSpinner           matlab.ui.control.Spinner
        AmplitudeSpinnerLabel          matlab.ui.control.Label
        AmplitudeSpinner               matlab.ui.control.Spinner
        SquarewaveButton               matlab.ui.control.Button
        SawtoothButton                 matlab.ui.control.Button
        TriangularwaveButton           matlab.ui.control.Button
        FullwaverectifiedButton        matlab.ui.control.Button
        HalfwaverectifiedButton        matlab.ui.control.Button
        RectangularwaveButton          matlab.ui.control.Button
        ImpulsetrainButton             matlab.ui.control.Button
        HorizontalShiftSpinnerLabel    matlab.ui.control.Label
        HorizontalShiftSpinner         matlab.ui.control.Spinner
        PhaseShiftdegreesSpinnerLabel  matlab.ui.control.Label
        PhaseShiftdegreesSpinner       matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        A % Amplitude
        N % Harmonics
        freq % Frequency
        n % Time domain
        hs % Horizontal Shift
        ps % Phase Shift
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TimedomainSpinner
        function TimedomainSpinnerValueChanged(app, event)
            app.n = app.TimedomainSpinner.Value;
       
        end

        % Value changed function: FrequencySpinner
        function FrequencySpinnerValueChanged(app, event)
            app.freq = app.FrequencySpinner.Value;
            
        end

        % Value changed function: NoofHarmonicsSpinner
        function NoofHarmonicsSpinnerValueChanged(app, event)
            app.N = app.NoofHarmonicsSpinner.Value;
            
        end

        % Value changed function: AmplitudeSpinner
        function AmplitudeSpinnerValueChanged(app, event)
            app.A = app.AmplitudeSpinner.Value;
            
        end

        % Button pushed function: SquarewaveButton
        function SquarewaveButtonPushed(app, event)
            t = linspace(-app.n,app.n,app.N);
f = 0*t;
w0 = 2*pi*app.freq;
            for k=-app.N:1:app.N
    if(k==0)
        continue;
    end
    C_k = ((app.A)/(1i*pi*k))*(1-exp(-1i*pi*k));
    f_k = C_k*exp(1i*k*w0*(t-app.ps));
    f = f + f_k;
            end
        
plot(app.UIAxes, t, f+app.hs, 'LineWidth', 2);
        end

        % Button pushed function: SawtoothButton
        function SawtoothButtonPushed(app, event)
                        t = linspace(-app.n,app.n,app.N);
f = 0*t;
w0 = 2*pi*app.freq;
for k=-app.N:1:app.N
    if(k==0)
        C_k =(app.A/2);
    else C_k = ((app.A*1i)/(2*pi*k));
    end
    f_k = C_k*exp(1i*k*w0*(t-app.ps));
    f = f + f_k;
end
plot(app.UIAxes, t, f+app.hs, 'LineWidth', 2);
        end

        % Button pushed function: TriangularwaveButton
        function TriangularwaveButtonPushed(app, event)
                  t = linspace(-app.n,app.n,app.N);
f = 0*t;
w0 = 2*pi*app.freq;
 for L=1:length(t)
fs = 0;
for k=1:app.N
    an = ( (4*app.A*sin(2*pi*k)/(2*pi*k)) + (4*app.A*cos(2*pi*k)/(2*pi*k)^2)- (4*app.A/(2*pi*k)^2));
    bn = ( (4*app.A*sin(2*pi*k)/(2*pi*k)^2) + (cos(2*pi*k)/(2*pi*k)));
      C0 = app.A/2;
      an = -(4*app.A)/((pi^2)*(2*k-1)^2);
      bn = 0;
      fs = fs + (an*cos((2*k-1)*w0*(t(L)-app.ps)));
end
f(L) = C0 + fs;
 end
 plot(app.UIAxes, t, f+app.hs, 'LineWidth', 2);
        end

        % Button pushed function: FullwaverectifiedButton
        function FullwaverectifiedButtonPushed(app, event)
             t = linspace(-app.n,app.n,app.N);
f = 0*t;
w0 = 2*pi*app.freq;
for k=-app.N:1:app.N
    if(k==0)
        C_k =(2*app.A/pi);
    else  C_k = ((-2*app.A)/(pi*((4*k^2)-1)));
    end
    f_k = C_k*exp(1i*k*w0*(t-app.ps));
    f = f + f_k;
end
 plot(app.UIAxes, t, f+app.hs, 'LineWidth', 2);
        end

        % Button pushed function: HalfwaverectifiedButton
        function HalfwaverectifiedButtonPushed(app, event)
            t = linspace(-app.n,app.n,app.N);
f = 0*t;
w0 = 2*pi*app.freq;
for k=-app.N:1:app.N
    
    C_k = ((cos(2*k*w0*t))/((4*k^2)-1));
    
end
    f_k = app.A/pi + (app.A/2)*sin(w0*(t-app.ps)) - ((2*app.A)/pi)*C_k + 0.1833*app.A;
    f = f + f_k;
    plot(app.UIAxes, t, f+app.hs, 'LineWidth', 2);
        end

        % Button pushed function: RectangularwaveButton
        function RectangularwaveButtonPushed(app, event)
            t = linspace(-app.n,app.n,app.N);
f = 0*t;
w0 = 2*pi*app.freq;
for L=1:length(t)
fs = 0;
for k=1:app.N
    an = ( (4*app.A*sin(2*pi*k)/(2*pi*k)) + (4*app.A*cos(2*pi*k)/(2*pi*k)^2)- (4*app.A/(2*pi*k)^2));
    bn = ( (4*app.A*sin(2*pi*k)/(2*pi*k)^2) + (cos(2*pi*k)/(2*pi*k)));
      C0 = app.A/2;
      an =( (2*app.A)/(k*pi))*sin((pi*k)/2);
      bn = 0;
      fs = fs +((an*cos(k*w0*(t(L)-app.ps)))+(bn*sin(k*w0*(t(L)-app.ps))));
end
f(L) = C0 + fs;
end
    plot(app.UIAxes, t, f+app.hs, 'LineWidth', 2);
        end

        % Button pushed function: ImpulsetrainButton
        function ImpulsetrainButtonPushed(app, event)
            t = linspace(-app.n,app.n,app.N);
f = 0*t;
w0 = 2*pi*app.freq;
for L=1:length(t)
fs = 0;
for k=1:app.N
    an = ( (4*app.A*sin(2*pi*k)/(2*pi*k)) + (4*app.A*cos(2*pi*k)/(2*pi*k)^2)- (4*app.A/(2*pi*k)^2));
    bn = ( (4*app.A*sin(2*pi*k)/(2*pi*k)^2) + (cos(2*pi*k)/(2*pi*k)));
      E0 = 0.00495*app.n;
      dis = E0*app.freq;
      C0 = app.A*dis;
      an = ((2*app.A)/(k*pi))*sin((pi*k)*dis);
      bn = 0;
      fs = fs +((an*cos(k*w0*(t(L)-app.ps)))+(bn*sin(k*w0*(t(L)-app.ps))));
end
f(L) = C0 + fs;
end
plot(app.UIAxes, t, f+app.hs, 'LineWidth', 2);
        end

        % Value changed function: HorizontalShiftSpinner
        function HorizontalShiftSpinnerValueChanged(app, event)
            app.hs = app.HorizontalShiftSpinner.Value+0;
            
        end

        % Value changed function: PhaseShiftdegreesSpinner
        function PhaseShiftdegreesSpinnerValueChanged(app, event)
            app.ps = app.PhaseShiftdegreesSpinner.Value*(pi/180)+0;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Fourier Series')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'f(t)')
            app.TimedomainSpinnerLabel = uilabel(app.UIFigure);
            app.TimedomainSpinnerLabel.Text = 'Time domain';
            app.TimedomainSpinner = uispinner(app.UIFigure);
            app.TimedomainSpinner.ValueChangedFcn = createCallbackFcn(app, @TimedomainSpinnerValueChanged, true);
            app.FrequencySpinnerLabel = uilabel(app.UIFigure);
            app.FrequencySpinnerLabel.Text = 'Frequency';
            app.FrequencySpinner = uispinner(app.UIFigure);
            app.FrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencySpinnerValueChanged, true);
            app.NoofHarmonicsSpinnerLabel = uilabel(app.UIFigure);
            app.NoofHarmonicsSpinnerLabel.Text = 'No. of Harmonics';
            app.NoofHarmonicsSpinner = uispinner(app.UIFigure);
            app.NoofHarmonicsSpinner.ValueChangedFcn = createCallbackFcn(app, @NoofHarmonicsSpinnerValueChanged, true);
            app.AmplitudeSpinnerLabel = uilabel(app.UIFigure);
            app.AmplitudeSpinnerLabel.Text = 'Amplitude';
            app.AmplitudeSpinner = uispinner(app.UIFigure);
            app.AmplitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSpinnerValueChanged, true);
            app.SquarewaveButton = uibutton(app.UIFigure, 'push');
            app.SquarewaveButton.ButtonPushedFcn = createCallbackFcn(app, @SquarewaveButtonPushed, true);
            app.SquarewaveButton.Text = 'Square wave';
            app.SawtoothButton = uibutton(app.UIFigure, 'push');
            app.SawtoothButton.ButtonPushedFcn = createCallbackFcn(app, @SawtoothButtonPushed, true);
            app.SawtoothButton.Text = 'Sawtooth';
            app.TriangularwaveButton = uibutton(app.UIFigure, 'push');
            app.TriangularwaveButton.ButtonPushedFcn = createCallbackFcn(app, @TriangularwaveButtonPushed, true);
            app.TriangularwaveButton.Text = 'Triangular wave';
            app.FullwaverectifiedButton = uibutton(app.UIFigure, 'push');
            app.FullwaverectifiedButton.ButtonPushedFcn = createCallbackFcn(app, @FullwaverectifiedButtonPushed, true);
            app.FullwaverectifiedButton.Text = 'Full-wave rectified';
            app.HalfwaverectifiedButton = uibutton(app.UIFigure, 'push');
            app.HalfwaverectifiedButton.ButtonPushedFcn = createCallbackFcn(app, @HalfwaverectifiedButtonPushed, true);
            app.HalfwaverectifiedButton.Text = 'Half-wave rectified';
            app.RectangularwaveButton = uibutton(app.UIFigure, 'push');
            app.RectangularwaveButton.ButtonPushedFcn = createCallbackFcn(app, @RectangularwaveButtonPushed, true);
            app.RectangularwaveButton.Text = 'Rectangular wave';
            app.ImpulsetrainButton = uibutton(app.UIFigure, 'push');
            app.ImpulsetrainButton.ButtonPushedFcn = createCallbackFcn(app, @ImpulsetrainButtonPushed, true);
            app.ImpulsetrainButton.Text = 'Impulse train';
            app.HorizontalShiftSpinnerLabel = uilabel(app.UIFigure);
            app.HorizontalShiftSpinnerLabel.Text = 'Horizontal Shift';
            app.HorizontalShiftSpinner = uispinner(app.UIFigure);
            app.HorizontalShiftSpinner.ValueChangedFcn = createCallbackFcn(app, @HorizontalShiftSpinnerValueChanged, true);
            app.PhaseShiftdegreesSpinnerLabel = uilabel(app.UIFigure);
            app.PhaseShiftdegreesSpinnerLabel.Text = 'Phase Shift(degrees)';
            app.PhaseShiftdegreesSpinner = uispinner(app.UIFigure);
            app.PhaseShiftdegreesSpinner.ValueChangedFcn = createCallbackFcn(app, @PhaseShiftdegreesSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CEN320phase2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end